classdef DEMO_Sung < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        QAMTx2Rx2Label            matlab.ui.control.Label
        DetectorDropDown          matlab.ui.control.DropDown
        DetectorLabel             matlab.ui.control.Label
        StateDropDown             matlab.ui.control.DropDown
        StateDropDownLabel        matlab.ui.control.Label
        AverageDecodePanel        matlab.ui.container.Panel
        bitssHzLabel_2            matlab.ui.control.Label
        DataCapacityAvgLDPC       matlab.ui.control.Label
        CapacityLabel_4           matlab.ui.control.Label
        secLabel_2                matlab.ui.control.Label
        kbpsLabel_2               matlab.ui.control.Label
        DataTimeAvgLDPC           matlab.ui.control.Label
        DataThroughputAvgLDPC     matlab.ui.control.Label
        DataBERAvgLDPC            matlab.ui.control.Label
        TimeLabel_2               matlab.ui.control.Label
        ThroughputLabel_2         matlab.ui.control.Label
        BERLabel_2                matlab.ui.control.Label
        DecodeLDPCPanel           matlab.ui.container.Panel
        bitssHzLabel              matlab.ui.control.Label
        DataCapacityLDPC          matlab.ui.control.Label
        CapacityLabel_2           matlab.ui.control.Label
        secLabel_6                matlab.ui.control.Label
        kbpsLabel_4               matlab.ui.control.Label
        DataTimeLDPC              matlab.ui.control.Label
        DataThroughputLDPC        matlab.ui.control.Label
        DataBERLDPC               matlab.ui.control.Label
        TimeLabel_4               matlab.ui.control.Label
        ThroughputLabel_4         matlab.ui.control.Label
        BERLabel_4                matlab.ui.control.Label
        UncodeQAMDEMODPanel       matlab.ui.container.Panel
        bitssHzLabel_4            matlab.ui.control.Label
        DataCapacityQAM           matlab.ui.control.Label
        CapacityLabel             matlab.ui.control.Label
        secLabel_4                matlab.ui.control.Label
        kbpsLabel_3               matlab.ui.control.Label
        DataTimeQAM               matlab.ui.control.Label
        DataThroughputQAM         matlab.ui.control.Label
        DataBERQAM                matlab.ui.control.Label
        TimeLabel_3               matlab.ui.control.Label
        ThroughputLabel_3         matlab.ui.control.Label
        BERLabel_3                matlab.ui.control.Label
        AverageUncodePanel        matlab.ui.container.Panel
        bitssHzLabel_3            matlab.ui.control.Label
        DataCapacityAvgQAM        matlab.ui.control.Label
        CapacityLabel_3           matlab.ui.control.Label
        secLabel                  matlab.ui.control.Label
        kbpsLabel                 matlab.ui.control.Label
        DataTimeAvgQAM            matlab.ui.control.Label
        DataThroughputAvgQAM      matlab.ui.control.Label
        DataBERAvgQAM             matlab.ui.control.Label
        TimeLabel                 matlab.ui.control.Label
        ThroughputLabel           matlab.ui.control.Label
        BERLabel                  matlab.ui.control.Label
        SNRPanel                  matlab.ui.container.Panel
        dBLabel_3                 matlab.ui.control.Label
        SNR                       matlab.ui.control.NumericEditField
        TimeEditField_2Label_2    matlab.ui.control.Label
        dBLabel                   matlab.ui.control.Label
        dBLabel_2                 matlab.ui.control.Label
        DataRx2SNR                matlab.ui.control.Label
        DataRx1SNR                matlab.ui.control.Label
        Rx2SNRLabel               matlab.ui.control.Label
        Rx1SNRLabel               matlab.ui.control.Label
        SetUpPanel                matlab.ui.container.Panel
        SNRweightEditField        matlab.ui.control.NumericEditField
        SNRweightEditFieldLabel   matlab.ui.control.Label
        WindowEditField           matlab.ui.control.NumericEditField
        WindowEditFieldLabel      matlab.ui.control.Label
        CFO_ignore                matlab.ui.control.NumericEditField
        CFO_ignoreEditFieldLabel  matlab.ui.control.Label
        Time                      matlab.ui.control.NumericEditField
        TimeEditField_2Label      matlab.ui.control.Label
        STARTButton               matlab.ui.control.Button
        LDPC                      matlab.ui.control.UIAxes
        QAM_Demodualtion          matlab.ui.control.UIAxes
        Original_Picture          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            JPG_origin  = imread('Cat.jpg');
            JPG_origin  = imresize(JPG_origin,[384,384]);
            imshow(JPG_origin,'Parent',app.Original_Picture);
            DTinfo = app.DetectorDropDown.Value;
            load('frame_data.mat');
            load('Tx_signal.mat');
            load('RandOrder.mat');
            frame_data.LMMSE = init_LMMSE( app.WindowEditField.Value , app.SNRweightEditField.Value );
            pause(0.01);
            switch app.StateDropDown.Value
                case '模擬'
                    Rx1_SNR                 = 0;
                    Rx2_SNR                 = 0;
                    sum_time                = 0;
                    sum_time_LDPC           = 0;
                    Biterror_all            = 0;
                    Biterror_all_LDPC       = 0;
                    Capacity_sum_all        = 0;
                    Capacity_sum_all_LDPC   = 0;
                    for i=1:app.Time.Value
                        Rx_signal = simChannel(Tx_signal,app.SNR.Value);

                        [Time,Biterror,Capacity_sum,Rx1_SNR,Rx2_SNR,JPG_RGB] = Rx_fun(frame_data,Rx_signal,DTinfo,app.CFO_ignore.Value,RandOrder);
                        if(Time~=-1)
                            BER = Biterror/prod(size(frame_data.JPG_bin));
                            Capacity_sum_all  = Capacity_sum + Capacity_sum_all;
                            app.DataTimeQAM.Text   = num2str( Time );
                            app.DataBERQAM.Text = num2str( BER ,'%2.2e');
                            app.DataThroughputQAM.Text  = num2str( prod(size(frame_data.JPG_bin)) * (1-BER)/Time /1e3 ,'%.1f');
                            app.DataCapacityQAM.Text = num2str(Capacity_sum/1644/560);
                            imshow(JPG_RGB,'Parent',app.QAM_Demodualtion);
                            pause(0.01);
                            Biterror_all = Biterror_all + Biterror;
                            sum_time     = sum_time     + Time;
                        end
    
                        [Time,Biterror,Capacity_sum,Rx1_SNR,Rx2_SNR,JPG_RGB] = Rx_fun_LDPC(frame_data,Rx_signal,DTinfo,app.CFO_ignore.Value,5,RandOrder);
                        if(Time~=-1)
                            BER = Biterror/prod(size(frame_data.JPG_bin));
                            Capacity_sum_all_LDPC = Capacity_sum + Capacity_sum_all_LDPC;
                            app.DataTimeLDPC.Text = num2str( Time );
                            app.DataBERLDPC.Text = num2str( BER ,'%2.2e');
                            app.DataThroughputLDPC.Text  = num2str( prod(size(frame_data.JPG_bin)) * (1-BER)/Time /1e3 ,'%.1f');
                            app.DataCapacityLDPC.Text = num2str(Capacity_sum/1644/560);
                            imshow(JPG_RGB,'Parent',app.LDPC);
                            pause(0.01);
                            Biterror_all_LDPC = Biterror_all_LDPC + Biterror;
                            sum_time_LDPC     = sum_time_LDPC     + Time;
                        end
    
                            app.DataRx1SNR.Text = num2str(Rx1_SNR);
                            app.DataRx2SNR.Text = num2str(Rx2_SNR);
                    end
                    BER_avg              = Biterror_all     /prod(size(frame_data.JPG_bin))/app.Time.Value;
                    BER_avg_LDPC         = Biterror_all_LDPC/prod(size(frame_data.JPG_bin))/app.Time.Value;
                    app.DataTimeAvgQAM.Text    = num2str(sum_time     /app.Time.Value);
                    app.DataTimeAvgLDPC.Text   = num2str(sum_time_LDPC/app.Time.Value);
                    app.DataBERAvgQAM.Text  = num2str( BER_avg      ,'%2.2e');
                    app.DataBERAvgLDPC.Text = num2str( BER_avg_LDPC ,'%2.2e');
                    app.DataThroughputAvgQAM.Text   = num2str( app.Time.Value * prod(size(frame_data.JPG_bin)) * (1-BER_avg     )/sum_time     /1e3 ,'%.1f');
                    app.DataThroughputAvgLDPC.Text  = num2str( app.Time.Value * prod(size(frame_data.JPG_bin)) * (1-BER_avg_LDPC)/sum_time_LDPC/1e3,'%.1f');
                    app.DataCapacityAvgQAM.Text   = num2str(Capacity_sum_all/1644/560/app.Time.Value);
                    app.DataCapacityAvgLDPC.Text  = num2str(Capacity_sum_all_LDPC/1644/560/app.Time.Value);

                case '實作'
                    Rx1_SNR                 = 0;
                    Rx2_SNR                 = 0;
                    sum_time                = 0;
                    sum_time_LDPC           = 0;
                    Biterror_all            = 0;
                    Biterror_all_LDPC       = 0;
                    Capacity_sum_all        = 0;
                    Capacity_sum_all_LDPC   = 0;
                    
                    i = 0;
                    j = 0;
                    while i < app.Time.Value
                        Rx_signal = EX_fun();
                        
                        [Time,Biterror,Capacity_sum,Rx1_SNR,Rx2_SNR,JPG_RGB] = Rx_fun(frame_data,Rx_signal,DTinfo,app.CFO_ignore.Value,RandOrder);
                        if(Time~=-1)
                            Capacity_sum_all  = Capacity_sum + Capacity_sum_all;
                            BER = Biterror/prod(size(frame_data.JPG_bin));
                            app.DataTimeQAM.Text   = num2str( Time );
                            app.DataBERQAM.Text = num2str( BER ,'%2.2e');
                            app.DataThroughputQAM.Text  = num2str( prod(size(frame_data.JPG_bin)) * (1-BER)/Time /1e3 ,'%.1f');
                            app.DataCapacityQAM.Text = num2str(Capacity_sum/1644/560);
                            imshow(JPG_RGB,'Parent',app.QAM_Demodualtion);
                            pause(0.01);
                            Biterror_all = Biterror_all + Biterror;
                            sum_time     = sum_time     + Time;
                            i = i + 1;
                        else
                            app.DataRx1SNR.Text = num2str(0);
                            app.DataRx2SNR.Text = num2str(0);
                        end

                        [Time,Biterror,Capacity_sum,Rx1_SNR,Rx2_SNR,JPG_RGB] = Rx_fun_LDPC(frame_data,Rx_signal,DTinfo,app.CFO_ignore.Value,5,RandOrder);
                        if(Time~=-1)
                            BER = Biterror/prod(size(frame_data.JPG_bin));
                            Capacity_sum_all_LDPC = Capacity_sum + Capacity_sum_all_LDPC;
                            app.DataTimeLDPC.Text = num2str( Time );
                            app.DataBERLDPC.Text = num2str( BER ,'%2.2e');
                            app.DataThroughputLDPC.Text  = num2str( prod(size(frame_data.JPG_bin)) * (1-BER)/Time /1e3 ,'%.1f');
                            app.DataCapacityLDPC.Text = num2str(Capacity_sum/1644/560);
                            imshow(JPG_RGB,'Parent',app.LDPC);
                            pause(0.01);
                            Biterror_all_LDPC = Biterror_all_LDPC + Biterror;
                            sum_time_LDPC     = sum_time_LDPC     + Time;                        
                            app.DataRx1SNR.Text = num2str(Rx1_SNR);
                            app.DataRx2SNR.Text = num2str(Rx2_SNR);
                        else
                            app.DataRx1SNR.Text = num2str(0);
                            app.DataRx2SNR.Text = num2str(0);
                        end

                    end
                    BER_avg              = Biterror_all     /prod(size(frame_data.JPG_bin))/app.Time.Value;
                    BER_avg_LDPC         = Biterror_all_LDPC/prod(size(frame_data.JPG_bin))/app.Time.Value;
                    app.DataTimeAvgQAM.Text    = num2str(sum_time     /app.Time.Value);
                    app.DataTimeAvgLDPC.Text   = num2str(sum_time_LDPC/app.Time.Value);
                    app.DataBERAvgQAM.Text  = num2str( BER_avg      ,'%2.2e');
                    app.DataBERAvgLDPC.Text = num2str( BER_avg_LDPC ,'%2.2e');
                    app.DataThroughputAvgQAM.Text   = num2str( app.Time.Value * prod(size(frame_data.JPG_bin)) * (1-BER_avg     )/sum_time     /1e3 ,'%.1f');
                    app.DataThroughputAvgLDPC.Text  = num2str( app.Time.Value * prod(size(frame_data.JPG_bin)) * (1-BER_avg_LDPC)/sum_time_LDPC/1e3,'%.1f');
                    app.DataCapacityAvgQAM.Text   = num2str(Capacity_sum_all/1644/560/app.Time.Value);
                    app.DataCapacityAvgLDPC.Text  = num2str(Capacity_sum_all_LDPC/1644/560/app.Time.Value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Original_Picture = uiaxes(app.UIFigure);
            title(app.Original_Picture, 'Original Picture')
            app.Original_Picture.XTick = [];
            app.Original_Picture.XTickLabel = '';
            app.Original_Picture.YTick = [];
            app.Original_Picture.YTickLabel = '';
            app.Original_Picture.ZTick = [];
            app.QAM_Demodualtion = uiaxes(app.UIFigure);
            title(app.QAM_Demodualtion, 'Uncode (QAMDEMOD)')
            app.QAM_Demodualtion.XTick = [];
            app.QAM_Demodualtion.XTickLabel = '';
            app.QAM_Demodualtion.YTick = [];
            app.QAM_Demodualtion.ZTick = [];
            app.LDPC = uiaxes(app.UIFigure);
            title(app.LDPC, 'Decode (LDPC)')
            app.LDPC.XTick = [];
            app.LDPC.XTickLabel = '';
            app.LDPC.YTick = [];
            app.LDPC.YTickLabel = '';
            app.LDPC.ZTick = [];
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.SetUpPanel = uipanel(app.UIFigure);
            app.SetUpPanel.Title = 'Set Up';
            app.TimeEditField_2Label = uilabel(app.SetUpPanel);
            app.TimeEditField_2Label.Text = 'Time';
            app.Time = uieditfield(app.SetUpPanel, 'numeric');
            app.Time.Value = 20;
            app.CFO_ignoreEditFieldLabel = uilabel(app.SetUpPanel);
            app.CFO_ignoreEditFieldLabel.Text = 'CFO_ignore';
            app.CFO_ignore = uieditfield(app.SetUpPanel, 'numeric');
            app.CFO_ignore.Value = 15;
            app.WindowEditFieldLabel = uilabel(app.SetUpPanel);
            app.WindowEditFieldLabel.Text = 'Window';
            app.WindowEditField = uieditfield(app.SetUpPanel, 'numeric');
            app.WindowEditField.Value = 15;
            app.SNRweightEditFieldLabel = uilabel(app.SetUpPanel);
            app.SNRweightEditFieldLabel.Text = 'SNR weight';
            app.SNRweightEditField = uieditfield(app.SetUpPanel, 'numeric');
            app.SNRweightEditField.Value = 45;
            app.SNRPanel = uipanel(app.UIFigure);
            app.SNRPanel.Title = 'SNR';
            app.Rx1SNRLabel = uilabel(app.SNRPanel);
            app.Rx1SNRLabel.Text = 'Rx1 SNR';
            app.Rx2SNRLabel = uilabel(app.SNRPanel);
            app.Rx2SNRLabel.Text = 'Rx2 SNR';
            app.DataRx1SNR = uilabel(app.SNRPanel);
            app.DataRx1SNR.Text = '';
            app.DataRx2SNR = uilabel(app.SNRPanel);
            app.DataRx2SNR.Text = '';
            app.dBLabel_2 = uilabel(app.SNRPanel);
            app.dBLabel_2.Text = 'dB';
            app.dBLabel = uilabel(app.SNRPanel);
            app.dBLabel.Text = 'dB';
            app.TimeEditField_2Label_2 = uilabel(app.SNRPanel);
            app.TimeEditField_2Label_2.Text = 'Sim SNR';
            app.SNR = uieditfield(app.SNRPanel, 'numeric');
            app.SNR.Value = 20;
            app.dBLabel_3 = uilabel(app.SNRPanel);
            app.dBLabel_3.Text = 'dB';
            app.AverageUncodePanel = uipanel(app.UIFigure);
            app.AverageUncodePanel.Title = 'Average Uncode';
            app.BERLabel = uilabel(app.AverageUncodePanel);
            app.BERLabel.Text = 'BER:';
            app.ThroughputLabel = uilabel(app.AverageUncodePanel);
            app.ThroughputLabel.Text = 'Throughput:';
            app.TimeLabel = uilabel(app.AverageUncodePanel);
            app.TimeLabel.Text = 'Time:';
            app.DataBERAvgQAM = uilabel(app.AverageUncodePanel);
            app.DataBERAvgQAM.Text = '';
            app.DataThroughputAvgQAM = uilabel(app.AverageUncodePanel);
            app.DataThroughputAvgQAM.Text = '';
            app.DataTimeAvgQAM = uilabel(app.AverageUncodePanel);
            app.DataTimeAvgQAM.Text = '';
            app.kbpsLabel = uilabel(app.AverageUncodePanel);
            app.kbpsLabel.Text = 'kbps';
            app.secLabel = uilabel(app.AverageUncodePanel);
            app.secLabel.Text = 'sec';
            app.CapacityLabel_3 = uilabel(app.AverageUncodePanel);
            app.CapacityLabel_3.Text = 'Capacity:';
            app.DataCapacityAvgQAM = uilabel(app.AverageUncodePanel);
            app.DataCapacityAvgQAM.Text = '';
            app.bitssHzLabel_3 = uilabel(app.AverageUncodePanel);
            app.bitssHzLabel_3.Text = 'bits/s/Hz';
            app.UncodeQAMDEMODPanel = uipanel(app.UIFigure);
            app.UncodeQAMDEMODPanel.Title = 'Uncode (QAMDEMOD)';
            app.BERLabel_3 = uilabel(app.UncodeQAMDEMODPanel);
            app.BERLabel_3.Text = 'BER:';
            app.ThroughputLabel_3 = uilabel(app.UncodeQAMDEMODPanel);
            app.ThroughputLabel_3.Text = 'Throughput:';
            app.TimeLabel_3 = uilabel(app.UncodeQAMDEMODPanel);
            app.TimeLabel_3.Text = 'Time:';
            app.DataBERQAM = uilabel(app.UncodeQAMDEMODPanel);
            app.DataBERQAM.Text = '';
            app.DataThroughputQAM = uilabel(app.UncodeQAMDEMODPanel);
            app.DataThroughputQAM.Text = '';
            app.DataTimeQAM = uilabel(app.UncodeQAMDEMODPanel);
            app.DataTimeQAM.Text = '';
            app.kbpsLabel_3 = uilabel(app.UncodeQAMDEMODPanel);
            app.kbpsLabel_3.Text = 'kbps';
            app.secLabel_4 = uilabel(app.UncodeQAMDEMODPanel);
            app.secLabel_4.Text = 'sec';
            app.CapacityLabel = uilabel(app.UncodeQAMDEMODPanel);
            app.CapacityLabel.Text = 'Capacity:';
            app.DataCapacityQAM = uilabel(app.UncodeQAMDEMODPanel);
            app.DataCapacityQAM.Text = '';
            app.bitssHzLabel_4 = uilabel(app.UncodeQAMDEMODPanel);
            app.bitssHzLabel_4.Text = 'bits/s/Hz';
            app.DecodeLDPCPanel = uipanel(app.UIFigure);
            app.DecodeLDPCPanel.Title = 'Decode (LDPC)';
            app.BERLabel_4 = uilabel(app.DecodeLDPCPanel);
            app.BERLabel_4.Text = 'BER:';
            app.ThroughputLabel_4 = uilabel(app.DecodeLDPCPanel);
            app.ThroughputLabel_4.Text = 'Throughput:';
            app.TimeLabel_4 = uilabel(app.DecodeLDPCPanel);
            app.TimeLabel_4.Text = 'Time:';
            app.DataBERLDPC = uilabel(app.DecodeLDPCPanel);
            app.DataBERLDPC.Text = '';
            app.DataThroughputLDPC = uilabel(app.DecodeLDPCPanel);
            app.DataThroughputLDPC.Text = '';
            app.DataTimeLDPC = uilabel(app.DecodeLDPCPanel);
            app.DataTimeLDPC.Text = '';
            app.kbpsLabel_4 = uilabel(app.DecodeLDPCPanel);
            app.kbpsLabel_4.Text = 'kbps';
            app.secLabel_6 = uilabel(app.DecodeLDPCPanel);
            app.secLabel_6.Text = 'sec';
            app.CapacityLabel_2 = uilabel(app.DecodeLDPCPanel);
            app.CapacityLabel_2.Text = 'Capacity:';
            app.DataCapacityLDPC = uilabel(app.DecodeLDPCPanel);
            app.DataCapacityLDPC.Text = '';
            app.bitssHzLabel = uilabel(app.DecodeLDPCPanel);
            app.bitssHzLabel.Text = 'bits/s/Hz';
            app.AverageDecodePanel = uipanel(app.UIFigure);
            app.AverageDecodePanel.Title = 'Average Decode';
            app.BERLabel_2 = uilabel(app.AverageDecodePanel);
            app.BERLabel_2.Text = 'BER:';
            app.ThroughputLabel_2 = uilabel(app.AverageDecodePanel);
            app.ThroughputLabel_2.Text = 'Throughput:';
            app.TimeLabel_2 = uilabel(app.AverageDecodePanel);
            app.TimeLabel_2.Text = 'Time:';
            app.DataBERAvgLDPC = uilabel(app.AverageDecodePanel);
            app.DataBERAvgLDPC.Text = '';
            app.DataThroughputAvgLDPC = uilabel(app.AverageDecodePanel);
            app.DataThroughputAvgLDPC.Text = '';
            app.DataTimeAvgLDPC = uilabel(app.AverageDecodePanel);
            app.DataTimeAvgLDPC.Text = '';
            app.kbpsLabel_2 = uilabel(app.AverageDecodePanel);
            app.kbpsLabel_2.Text = 'kbps';
            app.secLabel_2 = uilabel(app.AverageDecodePanel);
            app.secLabel_2.Text = 'sec';
            app.CapacityLabel_4 = uilabel(app.AverageDecodePanel);
            app.CapacityLabel_4.Text = 'Capacity:';
            app.DataCapacityAvgLDPC = uilabel(app.AverageDecodePanel);
            app.DataCapacityAvgLDPC.Text = '';
            app.bitssHzLabel_2 = uilabel(app.AverageDecodePanel);
            app.bitssHzLabel_2.Text = 'bits/s/Hz';
            app.StateDropDownLabel = uilabel(app.UIFigure);
            app.StateDropDownLabel.Text = 'State';
            app.StateDropDown = uidropdown(app.UIFigure);
            app.StateDropDown.Items = {'模擬', '實作'};
            app.StateDropDown.Value = '模擬';
            app.DetectorLabel = uilabel(app.UIFigure);
            app.DetectorLabel.Text = 'Detector';
            app.DetectorDropDown = uidropdown(app.UIFigure);
            app.DetectorDropDown.Items = {'ZF', 'LMMSE'};
            app.DetectorDropDown.Value = 'ZF';
            app.QAMTx2Rx2Label = uilabel(app.UIFigure);
            app.QAMTx2Rx2Label.Text = '16-QAM, Tx: 2, Rx = 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DEMO_Sung

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end